classdef dicegame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Player1              matlab.ui.container.Panel
        ROLLButton_online    matlab.ui.control.Button
        Image                matlab.ui.control.Image
        ReadyLabel           matlab.ui.control.Label
        STARTButton          matlab.ui.control.Button
        ScoreValue           matlab.ui.control.Label
        ScoreLabel           matlab.ui.control.Label
        SELECTSwitch         matlab.ui.control.RockerSwitch
        SELECTSwitchLabel    matlab.ui.control.Label
        ROLLButton           matlab.ui.control.Button
        CenterPanel          matlab.ui.container.Panel
        SnakeButton          matlab.ui.control.Button
        PickOneLabel         matlab.ui.control.Label
        DogButton            matlab.ui.control.Button
        ONLINEButton         matlab.ui.control.Button
        OFFLINEButton        matlab.ui.control.Button
        help                 matlab.ui.control.Image
        HelpButton           matlab.ui.control.Button
        BattleLog            matlab.ui.control.Table
        BattleLogButton      matlab.ui.control.Button
        WinLabel             matlab.ui.control.Label
        Player2Label         matlab.ui.control.Label
        Player1Label         matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        Label                matlab.ui.control.Label
        HealthValue          matlab.ui.control.Label
        HealthValue_2        matlab.ui.control.Label
        HealthLabel_2        matlab.ui.control.Label
        HealthLabel          matlab.ui.control.Label
        HealthBar2           matlab.ui.control.Image
        HealthBar1           matlab.ui.control.Image
        Background           matlab.ui.control.Image
        Player2              matlab.ui.container.Panel
        ROLLButton_2_online  matlab.ui.control.Button
        Image2               matlab.ui.control.Image
        ReadyLabel_2         matlab.ui.control.Label
        STARTButton_2        matlab.ui.control.Button
        ScoreValue_2         matlab.ui.control.Label
        ScoreLabel_2         matlab.ui.control.Label
        SELECTSwitch_2Label  matlab.ui.control.Label
        SELECTSwitch_2       matlab.ui.control.RockerSwitch
        ROLLButton_2         matlab.ui.control.Button
        ContextMenu          matlab.ui.container.ContextMenu
        Menu                 matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = public)
        channelID = 1670178;
         writeAPIKey='GVV3NUK6VQ1D8WU9';
         readAPIKey='CD8PG73I0LLX8OZ6';
         userAPIKey = '0164YBHQA20B4DS6';   
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.Background, 'Visible', 'off');
            set(app.Player1, 'Visible', 'off');
            set(app.Player2, 'Visible', 'off');
            set(app.BattleLog, 'Visible', 'off');
            set(app.HealthBar1, 'Visible', 'off');
            set(app.HealthBar2, 'Visible', 'off');
            set(app.HealthValue, 'Visible', 'off');
            set(app.HealthValue_2, 'Visible', 'off');
            set(app.HealthLabel, 'Visible', 'off');
            set(app.HealthLabel_2, 'Visible', 'off');
            set(app.Label, 'Visible', 'off');
            set(app.Label_2, 'Visible', 'off');
            set(app.Player1Label, 'Visible', 'off');
            set(app.Player2Label, 'Visible', 'off');
            set(app.PickOneLabel, 'Visible', 'off');
        end

        % Button pushed function: ROLLButton
        function ROLLButtonPushed(app, event)
            global number1 
            number1 = randi(6);
            
            global number2
            
            set(app.ROLLButton, 'Enable', 'off');
            app.help.ImageSource = imread("help.png");

          % Player 1's Dice Roll
            switch number1
                case 1
                    app.Image.ImageSource = imread("1.jpg");
                case 2
                    app.Image.ImageSource = imread("2.jpg");
                case 3
                    app.Image.ImageSource = imread("3.jpg");
                case 4
                    app.Image.ImageSource = imread("4.jpg");
                case 5
                    app.Image.ImageSource = imread("5.jpg");
                otherwise
                    app.Image.ImageSource = imread("6.jpg");
            end
            
            % Health Bar Display function for Player 1
            function DISPLAYPlayer1HealthBar(health)
                switch health
                    case 0
                        app.HealthBar1.ImageSource = imread("player1hp0.png");
                    case 1
                        app.HealthBar1.ImageSource = imread("player1hp1.png");
                    case 2
                        app.HealthBar1.ImageSource = imread("player1hp2.png");
                    case 3
                        app.HealthBar1.ImageSource = imread("player1hp3.png");
                    case 4
                        app.HealthBar1.ImageSource = imread("player1hp4.png");
                    case 5
                        app.HealthBar1.ImageSource = imread("player1hp5.png");
                    case 6
                        app.HealthBar1.ImageSource = imread("player1hp6.png");
                    case 7
                        app.HealthBar1.ImageSource = imread("player1hp7.png");
                    case 8
                        app.HealthBar1.ImageSource = imread("player1hp8.png");
                    case 9
                        app.HealthBar1.ImageSource = imread("player1hp9.png");
                    otherwise  
                        app.HealthBar1.ImageSource = imread("player1hp10.png");
                end
            end
            
            % Health Bar Display function for Player 2
            function DISPLAYPlayer2HealthBar(health)
                switch health
                    case 0
                        app.HealthBar2.ImageSource = imread("hp0.png");
                    case 1
                        app.HealthBar2.ImageSource = imread("hp1.png");
                    case 2
                        app.HealthBar2.ImageSource = imread("hp2.png");
                    case 3
                        app.HealthBar2.ImageSource = imread("hp3.png");
                    case 4
                        app.HealthBar2.ImageSource = imread("hp4.png");
                    case 5
                        app.HealthBar2.ImageSource = imread("hp5.png");
                    case 6
                        app.HealthBar2.ImageSource = imread("hp6.png");
                    case 7
                        app.HealthBar2.ImageSource = imread("hp7.png");
                    case 8
                        app.HealthBar2.ImageSource = imread("hp8.png");
                    case 9
                        app.HealthBar2.ImageSource = imread("hp9.png");
                    otherwise  
                        app.HealthBar2.ImageSource = imread("hp10.png");
                end
            end
            
            % Play Sound File function
            function PLAYSoundFile(filename)
                [y,Fs] = audioread(filename);
                sound(y,Fs);
                clear y Fs;
            end
            
   % WARNING: IF YOU ALTER THE BELOW CODE, MAKE SURE THAT PLAYER 2'S CODE 
   % IS ALSO CHANGED!


            if number1 > 0 & number2 > 0
              % Player 1 has the highest roll:
              
                if number1 > number2
                    switch app.SELECTSwitch.Value
                      % If Player 1 is attacking:
                        case 'ATTACK'
                            conv2num = str2double(app.HealthValue_2.Text);
                            newValue = conv2num - (number1 - number2);
                            DISPLAYPlayer2HealthBar(newValue);
                            conv2char = string(newValue);
                            app.HealthValue_2.Text = conv2char;
                            PLAYSoundFile("hiss.mp3");
                            
                          % To prevent health from dropping below zero: 
                            if str2double(app.HealthValue_2.Text) < 0
                                app.HealthValue_2.Text = "0";
                            end
                           
                          % Update Battle Log
                            app.BattleLog.Data = [app.BattleLog.Data;{number1 number2}];
                            
                          % Reset board:
                            number1 = 0;
                            number2 = 0; 
                            pause(3);
                            app.Image.ImageSource = imread("blank.jpg");
                            app.Image2.ImageSource = imread("blank.jpg");
                            set(app.ROLLButton, 'Enable', 'on');
                            set(app.ROLLButton_2, 'Enable', 'on');
                            
                      % If Player 1 is healing:
                        case 'HEAL'
                            PLAYSoundFile("bark.mp3");
                            conv2num = str2double(app.HealthValue.Text);
                            newValue = conv2num + (number1 - number2);
                            DISPLAYPlayer1HealthBar(newValue);
                            conv2char = string(newValue);
                            app.HealthValue.Text = conv2char;
                            
                          % To prevent health from going above ten:                             
                            if str2double(app.HealthValue.Text) > 10
                                app.HealthValue.Text = "10";
                            end
                          
                          % Update Battle Log
                            app.BattleLog.Data = [app.BattleLog.Data;{number1 number2}];
                          
                          % Reset board:
                            number1 = 0;
                            number2 = 0; 
                            pause(3);
                            app.Image.ImageSource = imread("blank.jpg");
                            app.Image2.ImageSource = imread("blank.jpg");
                            set(app.ROLLButton, 'Enable', 'on');
                            set(app.ROLLButton_2, 'Enable', 'on');
                    end
                
              % Player 2 has the highest roll:    
                else
                    switch app.SELECTSwitch_2.Value
                      % If Player 2 is attacking:
                        case 'ATTACK'
                            conv2num = str2double(app.HealthValue.Text);
                            newValue = conv2num - (number2 - number1);
                            DISPLAYPlayer1HealthBar(newValue);
                            conv2char = string(newValue);
                            app.HealthValue.Text = conv2char;
                            PLAYSoundFile("whimper.wav");
                            
                          % To prevent health from dropping below zero:
                            if str2double(app.HealthValue.Text) < 0
                                app.HealthValue.Text = "0";
                            end
                            
                          % Update Battle Log
                            app.BattleLog.Data = [app.BattleLog.Data;{number1 number2}];
                            
                          % Reset board:
                            number1 = 0;
                            number2 = 0;
                            pause(3);
                            app.Image.ImageSource = imread("blank.jpg");
                            app.Image2.ImageSource = imread("blank.jpg");
                            set(app.ROLLButton, 'Enable', 'on');
                            set(app.ROLLButton_2, 'Enable', 'on');
                            
                      % If Player 2 is healing:    
                        case 'HEAL'
                            PLAYSoundFile("lunge.mp3");
                            conv2num = str2double(app.HealthValue_2.Text);
                            newValue = conv2num + (number2 - number1);
                            DISPLAYPlayer2HealthBar(newValue);
                            conv2char = string(newValue);
                            app.HealthValue_2.Text = conv2char;
                            
                          % To prevent health from going above ten:
                            if str2double(app.HealthValue_2.Text) > 10
                                app.HealthValue_2.Text = "10";
                            end
                            
                          % Update Battle Log
                            app.BattleLog.Data = [app.BattleLog.Data;{number1 number2}];
                            
                          % Reset board:
                            number1 = 0;
                            number2 = 0;                                       
                            pause(3);
                            app.Image.ImageSource = imread("blank.jpg");
                            app.Image2.ImageSource = imread("blank.jpg");
                            set(app.ROLLButton, 'Enable', 'on');
                            set(app.ROLLButton_2, 'Enable', 'on');
                    end
                end
            end
            
          % Player 1 loses:
            if str2double(app.HealthValue.Text) <= 0
                app.HealthValue.Text = "0";
                DISPLAYPlayer1HealthBar(0);
                PLAYSoundFile("victory.mp3");
                score2num = str2double(app.ScoreValue_2.Text);
                newScore = score2num + 1;
                score2string = string(newScore);
                app.ScoreValue_2.Text = score2string;
                app.WinLabel.Text(8) = "2";
                set(app.WinLabel, 'Visible', 'on');
                
              % Reset Game:
                number1 = 0;
                number2 = 0;                
                set(app.ROLLButton, 'Enable', 'off');
                set(app.ROLLButton_2, 'Enable', 'off');
                pause(3);
                app.Image.ImageSource = imread("blank.jpg");
                app.Image2.ImageSource = imread("blank.jpg");
                DISPLAYPlayer1HealthBar(10);
                DISPLAYPlayer2HealthBar(10);
                app.HealthValue.Text = "10";
                app.HealthValue_2.Text = "10";
                set(app.ROLLButton, 'Enable', 'on');
                set(app.ROLLButton_2, 'Enable', 'on');
                set(app.WinLabel, 'Visible', 'off');
                app.BattleLog.Data = {};
            end
            
          % Player 2 loses:
            if str2double(app.HealthValue_2.Text) <= 0
                app.HealthValue_2.Text = "0";
                DISPLAYPlayer2HealthBar(0);
                PLAYSoundFile("victory.mp3");
                score2num = str2double(app.ScoreValue.Text);
                newScore = score2num + 1;
                score2string = string(newScore);
                app.ScoreValue.Text = score2string;
                app.WinLabel.Text(8) = "1";
                set(app.WinLabel, 'Visible', 'on');
                
              % Reset Game:
                number1 = 0;
                number2 = 0;                
                set(app.ROLLButton, 'Enable', 'off');
                set(app.ROLLButton_2, 'Enable', 'off');                                       
                pause(3);
                app.Image.ImageSource = imread("blank.jpg");
                app.Image2.ImageSource = imread("blank.jpg");
                DISPLAYPlayer1HealthBar(10);
                DISPLAYPlayer2HealthBar(10);
                app.HealthValue.Text = "10";
                app.HealthValue_2.Text = "10";
                set(app.ROLLButton, 'Enable', 'on');
                set(app.ROLLButton_2, 'Enable', 'on');
                set(app.WinLabel, 'Visible', 'off');
                app.BattleLog.Data = {};
            end
        end

        % Button pushed function: ROLLButton_2
        function ROLLButton_2Pushed(app, event)
            global number2
            number2 = randi(6);
            
            global number1
            
            set(app.ROLLButton_2, 'Enable', 'off');
            
            %Player 2's Dice Roll:
            switch number2
                case 1
                    app.Image2.ImageSource = imread("1.jpg");
                case 2
                    app.Image2.ImageSource = imread("2.jpg");
                case 3
                    app.Image2.ImageSource = imread("3.jpg");
                case 4
                    app.Image2.ImageSource = imread("4.jpg");
                case 5
                    app.Image2.ImageSource = imread("5.jpg");
                otherwise
                    app.Image2.ImageSource = imread("6.jpg");
            end
            
            % Health Bar Display function for Player 1
            function DISPLAYPlayer1HealthBar(health)
                switch health
                    case 0
                        app.HealthBar1.ImageSource = imread("player1hp0.png");
                    case 1
                        app.HealthBar1.ImageSource = imread("player1hp1.png");
                    case 2
                        app.HealthBar1.ImageSource = imread("player1hp2.png");
                    case 3
                        app.HealthBar1.ImageSource = imread("player1hp3.png");
                    case 4
                        app.HealthBar1.ImageSource = imread("player1hp4.png");
                    case 5
                        app.HealthBar1.ImageSource = imread("player1hp5.png");
                    case 6
                        app.HealthBar1.ImageSource = imread("player1hp6.png");
                    case 7
                        app.HealthBar1.ImageSource = imread("player1hp7.png");
                    case 8
                        app.HealthBar1.ImageSource = imread("player1hp8.png");
                    case 9
                        app.HealthBar1.ImageSource = imread("player1hp9.png");
                    otherwise  
                        app.HealthBar1.ImageSource = imread("player1hp10.png");
                end
            end
            
            % Health Bar Display function for Player 2
            function DISPLAYPlayer2HealthBar(health)
                switch health
                    case 0
                        app.HealthBar2.ImageSource = imread("hp0.png");
                    case 1
                        app.HealthBar2.ImageSource = imread("hp1.png");
                    case 2
                        app.HealthBar2.ImageSource = imread("hp2.png");
                    case 3
                        app.HealthBar2.ImageSource = imread("hp3.png");
                    case 4
                        app.HealthBar2.ImageSource = imread("hp4.png");
                    case 5
                        app.HealthBar2.ImageSource = imread("hp5.png");
                    case 6
                        app.HealthBar2.ImageSource = imread("hp6.png");
                    case 7
                        app.HealthBar2.ImageSource = imread("hp7.png");
                    case 8
                        app.HealthBar2.ImageSource = imread("hp8.png");
                    case 9
                        app.HealthBar2.ImageSource = imread("hp9.png");
                    otherwise  
                        app.HealthBar2.ImageSource = imread("hp10.png");
                end
            end
            
            % Play Sound File function
            function PLAYSoundFile(filename)
                [y,Fs] = audioread(filename);
                sound(y,Fs);
                clear y Fs;
            end
            
   %  The rest of the code is the same as Player 1's, so that way it doesn't
   %  matter which person rolls first.
   
   %  WARNING: IF YOU ALTER THE BELOW CODE, MAKE SURE THAT PLAYER 1'S CODE 
   %  IS ALSO CHANGED!
            
            if number1 > 0 & number2 > 0
              % Player 1 has the highest roll:
                if number1 > number2
                    switch app.SELECTSwitch.Value
                      % If Player 1 is attacking:
                        case 'ATTACK'
                            conv2num = str2double(app.HealthValue_2.Text);
                            newValue = conv2num - (number1 - number2);
                            DISPLAYPlayer2HealthBar(newValue);
                            conv2char = string(newValue);
                            app.HealthValue_2.Text = conv2char;
                            PLAYSoundFile("hiss.mp3");
                            
                          % To prevent health from dropping below zero: 
                            if str2double(app.HealthValue_2.Text) < 0
                                app.HealthValue_2.Text = "0";
                            end
                           
                          % Update Battle Log
                            app.BattleLog.Data = [app.BattleLog.Data;{number1 number2}];
                            
                          % Reset board:
                            number1 = 0;
                            number2 = 0; 
                            pause(3);
                            app.Image.ImageSource = imread("blank.jpg");
                            app.Image2.ImageSource = imread("blank.jpg");
                            set(app.ROLLButton, 'Enable', 'on');
                            set(app.ROLLButton_2, 'Enable', 'on');
                            
                      % If Player 1 is healing:
                        case 'HEAL'
                            PLAYSoundFile("bark.mp3");
                            conv2num = str2double(app.HealthValue.Text);
                            newValue = conv2num + (number1 - number2);
                            DISPLAYPlayer1HealthBar(newValue);
                            conv2char = string(newValue);
                            app.HealthValue.Text = conv2char;
                            
                          % To prevent health from going above ten:                             
                            if str2double(app.HealthValue.Text) > 10
                                app.HealthValue.Text = "10";
                            end
                         
                          % Update Battle Log
                            app.BattleLog.Data = [app.BattleLog.Data;{number1 number2}];
                            
                          % Reset board:
                            number1 = 0;
                            number2 = 0; 
                            pause(3);
                            app.Image.ImageSource = imread("blank.jpg");
                            app.Image2.ImageSource = imread("blank.jpg");
                            set(app.ROLLButton, 'Enable', 'on');
                            set(app.ROLLButton_2, 'Enable', 'on');
                    end
                
              % Player 2 has the highest roll:    
                else
                    switch app.SELECTSwitch_2.Value
                      % If Player 2 is attacking:
                        case 'ATTACK'
                            conv2num = str2double(app.HealthValue.Text);
                            newValue = conv2num - (number2 - number1);
                            DISPLAYPlayer1HealthBar(newValue);
                            conv2char = string(newValue);
                            app.HealthValue.Text = conv2char;
                            PLAYSoundFile("whimper.wav");
                            
                          % To prevent health from dropping below zero:
                            if str2double(app.HealthValue.Text) < 0
                                app.HealthValue.Text = "0";
                            end
                            
                          % Update Battle Log
                            app.BattleLog.Data = [app.BattleLog.Data;{number1 number2}];
                            
                          % Reset board:
                            number1 = 0;
                            number2 = 0;
                            pause(3);
                            app.Image.ImageSource = imread("blank.jpg");
                            app.Image2.ImageSource = imread("blank.jpg");
                            set(app.ROLLButton, 'Enable', 'on');
                            set(app.ROLLButton_2, 'Enable', 'on');
                            
                      % If Player 2 is healing:    
                        case 'HEAL'
                            PLAYSoundFile("lunge.mp3");
                            conv2num = str2double(app.HealthValue_2.Text);
                            newValue = conv2num + (number2 - number1);
                            DISPLAYPlayer2HealthBar(newValue);
                            conv2char = string(newValue);
                            app.HealthValue_2.Text = conv2char;
                            
                          % To prevent health from going above ten:
                            if str2double(app.HealthValue_2.Text) > 10
                                app.HealthValue_2.Text = "10";
                            end
                            
                          % Update Battle Log
                            app.BattleLog.Data = [app.BattleLog.Data;{number1 number2}];
                            
                          % Reset board:
                            number1 = 0;
                            number2 = 0;                                       
                            pause(3);
                            app.Image.ImageSource = imread("blank.jpg");
                            app.Image2.ImageSource = imread("blank.jpg");
                            set(app.ROLLButton, 'Enable', 'on');
                            set(app.ROLLButton_2, 'Enable', 'on');
                    end
                end
            end
            
          % Player 1 loses:
            if str2double(app.HealthValue.Text) <= 0
                app.HealthValue.Text = "0";
                DISPLAYPlayer1HealthBar(0);
                PLAYSoundFile("victory.mp3");
                score2num = str2double(app.ScoreValue_2.Text);
                newScore = score2num + 1;
                score2string = string(newScore);
                app.ScoreValue_2.Text = score2string;
                app.WinLabel.Text(8) = "2";
                set(app.WinLabel, 'Visible', 'on');
                
              % Reset Game:
                number1 = 0;
                number2 = 0;                
                set(app.ROLLButton, 'Enable', 'off');
                set(app.ROLLButton_2, 'Enable', 'off');
                pause(5);
                app.Image.ImageSource = imread("blank.jpg");
                app.Image2.ImageSource = imread("blank.jpg");
                DISPLAYPlayer1HealthBar(10);
                DISPLAYPlayer2HealthBar(10);
                app.HealthValue.Text = "10";
                app.HealthValue_2.Text = "10";
                set(app.ROLLButton, 'Enable', 'on');
                set(app.ROLLButton_2, 'Enable', 'on');
                set(app.WinLabel, 'Visible', 'off');
                app.BattleLog.Data = {};
            end
            
          % Player 2 loses:
            if str2double(app.HealthValue_2.Text) <= 0
                app.HealthValue_2.Text = "0";
                DISPLAYPlayer2HealthBar(0);
                PLAYSoundFile("victory.mp3");
                score2num = str2double(app.ScoreValue.Text);
                newScore = score2num + 1;
                score2string = string(newScore);
                app.ScoreValue.Text = score2string;
                app.WinLabel.Text(8) = "1";
                set(app.WinLabel, 'Visible', 'on');
                
              % Reset Game:
                number1 = 0;
                number2 = 0;                
                set(app.ROLLButton, 'Enable', 'off');
                set(app.ROLLButton_2, 'Enable', 'off');                                       
                pause(5);
                app.Image.ImageSource = imread("blank.jpg");
                app.Image2.ImageSource = imread("blank.jpg");
                DISPLAYPlayer1HealthBar(10);
                DISPLAYPlayer2HealthBar(10);
                app.HealthValue.Text = "10";
                app.HealthValue_2.Text = "10";
                set(app.ROLLButton, 'Enable', 'on');
                set(app.ROLLButton_2, 'Enable', 'on');
                set(app.WinLabel, 'Visible', 'off');
                app.BattleLog.Data = {};
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            set(app.STARTButton, 'Enable', 'off');
            
            if app.STARTButton.Enable == 'off'
                set(app.STARTButton, 'Visible', 'off');
                set(app.STARTButton_2, 'Visible', 'off');
                set(app.ReadyLabel, 'Visible', 'off');
                set(app.ReadyLabel_2, 'Visible', 'off');
                
                set(app.ROLLButton, 'Visible', 'on');
                set(app.SELECTSwitch, 'Visible', 'on');
                set(app.SELECTSwitchLabel, 'Visible', 'on');
                set(app.HealthValue, 'Visible', 'on');
                set(app.Player1Label, 'Visible', 'on');
                set(app.Player2Label, 'Visible', 'on');
                                
                set(app.ROLLButton_2, 'Visible', 'on');
                set(app.SELECTSwitch_2, 'Visible', 'on');
                set(app.SELECTSwitch_2Label, 'Visible', 'on');
                set(app.HealthValue_2, 'Visible', 'on');
                
                app.Image.ImageSource = imread("blank.jpg");
                app.Image2.ImageSource = imread("blank.jpg");
                set(app.Image, 'Visible', 'on');
                set(app.Image2, 'Visible', 'on');
            end
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            set(app.STARTButton_2, 'Enable', 'off');
          
            if app.STARTButton_2.Enable == 'off'
                set(app.STARTButton, 'Visible', 'off');
                set(app.STARTButton_2, 'Visible', 'off');
                set(app.ReadyLabel, 'Visible', 'off');
                set(app.ReadyLabel_2, 'Visible', 'off');
                
                set(app.ROLLButton, 'Visible', 'on');
                set(app.SELECTSwitch, 'Visible', 'on');
                set(app.SELECTSwitchLabel, 'Visible', 'on');
                set(app.HealthValue, 'Visible', 'on');
                set(app.HealthLabel, 'Visible', 'on');              
                set(app.ScoreValue, 'Visible', 'on');
                set(app.ScoreLabel, 'Visible', 'on');
                                
                set(app.ROLLButton_2, 'Visible', 'on');
                set(app.SELECTSwitch_2, 'Visible', 'on');
                set(app.SELECTSwitch_2Label, 'Visible', 'on');
                set(app.HealthValue_2, 'Visible', 'on');
                set(app.HealthLabel_2, 'Visible', 'on');                
                set(app.ScoreValue_2, 'Visible', 'on');
                set(app.ScoreLabel_2, 'Visible', 'on');
                
                app.Image.ImageSource = imread("blank.jpg");
                app.Image2.ImageSource = imread("blank.jpg");
                set(app.Image, 'Visible', 'on');
                set(app.Image2, 'Visible', 'on');
            end
        end

        % Button pushed function: BattleLogButton
        function BattleLogButtonPushed(app, event)
            if app.BattleLog.Visible == false
                set(app.BattleLog, 'Visible', 'on');

            else
                set(app.BattleLog, 'Visible', 'off');
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if app.help.Visible == false
                set(app.help, 'Visible', 'on');
            else
                set(app.help, 'Visible', 'off');
            end
        end

        % Button pushed function: ONLINEButton
        function ONLINEButtonPushed(app, event)
            set(app.OFFLINEButton, 'Visible', 'off');
            set(app.ONLINEButton, 'Visible', 'off');
            set(app.DogButton,'Visible','on');
            set(app.SnakeButton,'Visible','on');
            set(app.PickOneLabel,'Visible','on');
            set(app.ROLLButton,'Enable','off');
            set(app.ROLLButton_2,'Enable','off');
            set(app.ROLLButton,'Visible','off');
            app.help.ImageSource = imread("help.png");
            set(app.ROLLButton_2,'Visible','off');
            
        end

        % Button pushed function: OFFLINEButton
        function OFFLINEButtonPushed(app, event)
            set(app.Background, 'Visible', 'on');
            set(app.Player1, 'Visible', 'on');
            set(app.Player2, 'Visible', 'on');
            set(app.Player1Label, 'Visible', 'on');
            set(app.Player2Label, 'Visible', 'on');
            set(app.HealthBar1, 'Visible', 'on');
            set(app.HealthBar2, 'Visible', 'on');
            set(app.HealthValue, 'Visible', 'on');
            set(app.HealthValue_2, 'Visible', 'on');
            set(app.HealthLabel, 'Visible', 'on');
            set(app.HealthLabel_2, 'Visible', 'on');
            set(app.OFFLINEButton, 'Visible', 'off');
            set(app.ONLINEButton, 'Visible', 'off');
            app.help.ImageSource = imread("help.png");
            
        end

        % Button pushed function: DogButton
        function DogButtonPushed(app, event)
             
            set(app.Background, 'Visible', 'on');
            set(app.Player1, 'Visible', 'on');
            set(app.Player2, 'Visible', 'on');
            set(app.HealthBar1, 'Visible', 'on');
            set(app.HealthBar2, 'Visible', 'on');
            set(app.HealthValue, 'Visible', 'on');
            set(app.HealthValue_2, 'Visible', 'on');
            set(app.HealthLabel, 'Visible', 'on');
            set(app.HealthLabel_2, 'Visible', 'on');
            set(app.Label, 'Visible', 'on');
            set(app.Label_2, 'Visible', 'on');

            set(app.DogButton,'Visible','off');
            set(app.SnakeButton,'Visible','off');
            set(app.PickOneLabel,'Visible','off');
            set(app.ReadyLabel_2,'Visible','off');
            set(app.STARTButton_2,'Visible','off');
            set(app.STARTButton_2,'Enable','off');
            set(app.SELECTSwitch_2,'Visible','off');
            set(app.ROLLButton_2,'Visible','off');
            set(app.ROLLButton_2,'Enable','off');
            set(app.ROLLButton,'Visible','off');
            set(app.ROLLButton,'Enable','off');
            set(app.SELECTSwitch_2,'Enable','off');
            set(app.ROLLButton_online,'Enable','on');
            set(app.ROLLButton_online,'Visible','on');
            

        end

        % Button pushed function: SnakeButton
        function SnakeButtonPushed(app, event)
            
            set(app.Background, 'Visible', 'on');
            set(app.Player1, 'Visible', 'on');
            set(app.Player2, 'Visible', 'on');
            set(app.HealthBar1, 'Visible', 'on');
            set(app.HealthBar2, 'Visible', 'on');
            set(app.HealthValue, 'Visible', 'on');
            set(app.HealthValue_2, 'Visible', 'on');
            set(app.HealthLabel, 'Visible', 'on');
            set(app.HealthLabel_2, 'Visible', 'on');
            set(app.Label_2, 'Visible', 'on');
            set(app.Label, 'Visible', 'on');

            set(app.DogButton,'Visible','off');
            set(app.SnakeButton,'Visible','off');
            set(app.PickOneLabel,'Visible','off');
            set(app.ReadyLabel_2,'Visible','off');
            set(app.STARTButton,'Visible','off');
            set(app.STARTButton,'Enable','off');
            set(app.SELECTSwitch,'Visible','off');
            set(app.ROLLButton,'Visible','off');
            set(app.ROLLButton_2,'Visible','off');
            set(app.ROLLButton_2,'Enable','off');
            set(app.ROLLButton,'Enable','off');
            set(app.SELECTSwitch,'Enable','off');
            set(app.Image,'Visible','off');
            
            set(app.ROLLButton_2_online,'Enable','on');
            set(app.ROLLButton_2_online,'Visible','on');
            
        end

        % Button pushed function: ROLLButton_online
        function ROLLButton_onlinePushed(app, event)
        

            app.channelID = 1670178;
            app.writeAPIKey='GVV3NUK6VQ1D8WU9';
            app.readAPIKey='CD8PG73I0LLX8OZ6';
            app.userAPIKey = '0164YBHQA20B4DS6'; 
            url = sprintf('https://api.thingspeak.com/channels/%s/feeds.json?api_key=%s', num2str(app.channelID), app.userAPIKey);

            
            global n1 
            global n2
           
                    n1 = randi(6);
                    pause(15);
                    thingSpeakWrite(app.channelID, 'WriteKey', app.writeAPIKey, 'Fields', [1], 'Values', n1);
                    pause(15);
                    n2 = thingSpeakRead(app.channelID, 'readKey', app.readAPIKey, 'Fields', [2],'OutputFormat','matrix');
                    set(app.ROLLButton_online, 'Enable', 'on');
                    
        
                  % Player 1's Dice Roll
                    switch n1
                        case 1
                            app.Image.ImageSource = imread("1.jpg");
                        case 2
                            app.Image.ImageSource = imread("2.jpg");
                        case 3
                            app.Image.ImageSource = imread("3.jpg");
                        case 4
                            app.Image.ImageSource = imread("4.jpg");
                        case 5
                            app.Image.ImageSource = imread("5.jpg");
                        otherwise
                            app.Image.ImageSource = imread("6.jpg");
                    end
                    
                    % Health Bar Display function for Player 1
                    function DISPLAYPlayer1HealthBar(health)
                        switch health
                            case 0
                                app.HealthBar1.ImageSource = imread("player1hp0.png");
                            case 1
                                app.HealthBar1.ImageSource = imread("player1hp1.png");
                            case 2
                                app.HealthBar1.ImageSource = imread("player1hp2.png");
                            case 3
                                app.HealthBar1.ImageSource = imread("player1hp3.png");
                            case 4
                                app.HealthBar1.ImageSource = imread("player1hp4.png");
                            case 5
                                app.HealthBar1.ImageSource = imread("player1hp5.png");
                            case 6
                                app.HealthBar1.ImageSource = imread("player1hp6.png");
                            case 7
                                app.HealthBar1.ImageSource = imread("player1hp7.png");
                            case 8
                                app.HealthBar1.ImageSource = imread("player1hp8.png");
                            case 9
                                app.HealthBar1.ImageSource = imread("player1hp9.png");
                            otherwise  
                                app.HealthBar1.ImageSource = imread("player1hp10.png");
                        end
                    end

                    function DISPLAYPlayer2HealthBar(health)
                        switch health
                            case 0
                                app.HealthBar2.ImageSource = imread("hp0.png");
                            case 1
                                app.HealthBar2.ImageSource = imread("hp1.png");
                            case 2
                                app.HealthBar2.ImageSource = imread("hp2.png");
                            case 3
                                app.HealthBar2.ImageSource = imread("hp3.png");
                            case 4
                                app.HealthBar2.ImageSource = imread("hp4.png");
                            case 5
                                app.HealthBar2.ImageSource = imread("hp5.png");
                            case 6
                                app.HealthBar2.ImageSource = imread("hp6.png");
                            case 7
                                app.HealthBar2.ImageSource = imread("hp7.png");
                            case 8
                                app.HealthBar2.ImageSource = imread("hp8.png");
                            case 9
                                app.HealthBar2.ImageSource = imread("hp9.png");
                            otherwise  
                                app.HealthBar2.ImageSource = imread("hp10.png");
                        end
                    end
        
                    function PLAYSoundFile(filename)
                        [y,Fs] = audioread(filename);
                        sound(y,Fs);
                        clear y Fs;
                    end

                    
                    if n1 > 0
                        if n1 > n2
                                    conv2num = str2double(app.HealthValue_2.Text);
                                    newValue = conv2num - (n1-n2);
                                    DISPLAYPlayer2HealthBar(newValue);
                                    conv2char = string(newValue);
                                    thingSpeakWrite(app.channelID, 'WriteKey', app.writeAPIKey, 'Fields', [4], 'Values', newValue);
                                    app.HealthValue_2.Text = conv2char;
                                    PLAYSoundFile("hiss.mp3");
                                  % To prevent health from dropping below zero: 
                                    if str2double(app.HealthValue_2.Text) < 0
                                        app.HealthValue_2.Text = "0";
                                    end
                                   
                                  % Update Battle Log
                                    app.BattleLog.Data = [app.BattleLog.Data;{n1 n2}];
                                    
                                  % Reset board:
                                    n1 = 0;
                                    n2=0;
                                    thingSpeakWrite(app.channelID, 'WriteKey', app.writeAPIKey, 'Fields', [1], 'Values', n1);
                                    thingSpeakWrite(app.channelID, 'WriteKey', app.writeAPIKey, 'Fields', [2], 'Values', n2);
                                    pause(5);
                                    app.Image.ImageSource = imread("blank.jpg");
                                    set(app.ROLLButton_online, 'Enable', 'on');    
                        
                      % Player 2 has the highest roll:    
                        else
                            
                                    conv2num = str2double(app.HealthValue.Text);
                                    newValue = conv2num - n2;
                                    DISPLAYPlayer1HealthBar(newValue);
                                    pause(5);
                                    thingSpeakWrite(app.channelID, 'WriteKey', app.writeAPIKey, 'Fields', [3], 'Values', newValue);
                                    conv2char = string(newValue);
                                    app.HealthValue.Text = conv2char;
                                    PLAYSoundFile("whimper.wav");
                                    
                                  % To prevent health from dropping below zero:
                                    if str2double(app.HealthValue.Text) < 0
                                        app.HealthValue.Text = "0";
                                    end
                                    
                                  % Update Battle Log
                                    app.BattleLog.Data = [app.BattleLog.Data;{n1 n2}];
                                    
                                  % Reset board:
                                    n1 = 0;
                                    n2=0;
                                    thingSpeakWrite(app.channelID, 'WriteKey', app.writeAPIKey, 'Fields', [1], 'Values', n1);
                                    thingSpeakWrite(app.channelID, 'WriteKey', app.writeAPIKey, 'Fields', [2], 'Values', n2);
                                    pause(5);
                                    app.Image.ImageSource = imread("blank.jpg");
                                    set(app.ROLLButton_online, 'Enable', 'on');
                                    %set(app.ROLLButton_2, 'Enable', 'on');    
                                
                            end
                        end
                    
                    
                  % Player 1 loses:
                    if str2double(app.HealthValue.Text) <= 0
                        app.HealthValue.Text = "0";
                        DISPLAYPlayer1HealthBar(0);
                        PLAYSoundFile("victory.mp3");
                        score2num = str2double(app.ScoreValue_2.Text);
                        newScore = score2num + 1;
                        score2string = string(newScore);
                        thingSpeakWrite(app.channelID, 'WriteKey', app.writeAPIKey, 'Fields', [5], 'Values', newScore);
                        app.ScoreValue_2.Text = score2string;
                        app.WinLabel.Text(8) = "2";
                        set(app.WinLabel, 'Visible', 'on');
                        
                      % Reset Game:
                        n1 = 0;
                        n2=0;
                        thingSpeakWrite(app.channelID, 'WriteKey', app.writeAPIKey, 'Fields', [1], 'Values', n1);
                        thingSpeakWrite(app.channelID, 'WriteKey', app.writeAPIKey, 'Fields', [2], 'Values', n2);
                        set(app.ROLLButton_online, 'Enable', 'on');
                        pause(5);
                        app.Image.ImageSource = imread("blank.jpg");
                        DISPLAYPlayer1HealthBar(10);
                        DISPLAYPlayer2HealthBar(10);
                        app.HealthValue.Text = "10";
                        app.HealthValue_2.Text = "10";
                        set(app.ROLLButton_online, 'Enable', 'on');
                        set(app.WinLabel, 'Visible', 'off');
                        app.BattleLog.Data = {};
                        webwrite(url, weboptions('RequestMethod','delete'));
                    end
                    
                  % Player 2 loses:
                    if str2double(app.HealthValue_2.Text) <= 0
                        app.HealthValue_2.Text = "0";
                        DISPLAYPlayer2HealthBar(0);
                        PLAYSoundFile("victory.mp3");
                        score2num = str2double(app.ScoreValue.Text);
                        newScore = score2num + 1;
                        score2string = string(newScore);
                        thingSpeakWrite(app.channelID, 'WriteKey', app.writeAPIKey, 'Fields', [6], 'Values', newScore);
                        app.ScoreValue.Text = score2string;
                        app.WinLabel.Text(8) = "1";
                        set(app.WinLabel, 'Visible', 'on');
                        
                      % Reset Game:
                        n1 = 0;
                        n2 = 0;  
                        thingSpeakWrite(app.channelID, 'WriteKey', app.writeAPIKey, 'Fields', [1], 'Values', n1);
                        thingSpeakWrite(app.channelID, 'WriteKey', app.writeAPIKey, 'Fields', [2], 'Values', n2);
                        set(app.ROLLButton_online, 'Enable', 'on');                                       
                        pause(5);
                        app.Image.ImageSource = imread("blank.jpg");
                        DISPLAYPlayer1HealthBar(10);
                        DISPLAYPlayer2HealthBar(10);
                        app.HealthValue.Text = "10";
                        app.HealthValue_2.Text = "10";
                        set(app.ROLLButton_online, 'Enable', 'on');
                        set(app.WinLabel, 'Visible', 'off');
                        app.BattleLog.Data = {};
                        webwrite(url, weboptions('RequestMethod','delete'));
                    end
        end

        % Button pushed function: ROLLButton_2_online
        function ROLLButton_2_onlinePushed(app, event)
            

            app.channelID = 1670178;
            app.writeAPIKey='GVV3NUK6VQ1D8WU9';
            app.readAPIKey='CD8PG73I0LLX8OZ6';
            app.userAPIKey = '0164YBHQA20B4DS6'; 
            global num1 
            global num2
            url = sprintf('https://api.thingspeak.com/channels/%s/feeds.json?api_key=%s', num2str(app.channelID), app.userAPIKey);
                    
                    num2 = randi(6);
                    thingSpeakWrite(app.channelID, 'WriteKey', app.writeAPIKey, 'Fields', [2], 'Values', num2);
                    pause(5);
                    num1 = thingSpeakRead(app.channelID, 'readKey', app.readAPIKey, 'Fields', [1],'OutputFormat','matrix');
                    set(app.ROLLButton_2_online, 'Enable', 'on');
            
            %Player 2's Dice Roll:
            switch num2
                case 1
                    app.Image2.ImageSource = imread("1.jpg");
                case 2
                    app.Image2.ImageSource = imread("2.jpg");
                case 3
                    app.Image2.ImageSource = imread("3.jpg");
                case 4
                    app.Image2.ImageSource = imread("4.jpg");
                case 5
                    app.Image2.ImageSource = imread("5.jpg");
                otherwise
                    app.Image2.ImageSource = imread("6.jpg");
            end
            
            % Health Bar Display function for Player 1
            function DISPLAYPlayer1HealthBar(health)
                switch health
                    case 0
                        app.HealthBar1.ImageSource = imread("player1hp0.png");
                        app.Player1.Image = imread("player1hp0.png");
                    case 1
                        app.HealthBar1.ImageSource = imread("player1hp1.png");
                    case 2
                        app.HealthBar1.ImageSource = imread("player1hp2.png");
                    case 3
                        app.HealthBar1.ImageSource = imread("player1hp3.png");
                    case 4
                        app.HealthBar1.ImageSource = imread("player1hp4.png");
                    case 5
                        app.HealthBar1.ImageSource = imread("player1hp5.png");
                    case 6
                        app.HealthBar1.ImageSource = imread("player1hp6.png");
                    case 7
                        app.HealthBar1.ImageSource = imread("player1hp7.png");
                    case 8
                        app.HealthBar1.ImageSource = imread("player1hp8.png");
                    case 9
                        app.HealthBar1.ImageSource = imread("player1hp9.png");
                    otherwise  
                        app.HealthBar1.ImageSource = imread("player1hp10.png");
                end
            end
            
            % Health Bar Display function for Player 2
            function DISPLAYPlayer2HealthBar(health)
                switch health
                    case 0
                        app.HealthBar2.ImageSource = imread("hp0.png");
                    case 1
                        app.HealthBar2.ImageSource = imread("hp1.png");
                    case 2
                        app.HealthBar2.ImageSource = imread("hp2.png");
                    case 3
                        app.HealthBar2.ImageSource = imread("hp3.png");
                    case 4
                        app.HealthBar2.ImageSource = imread("hp4.png");
                    case 5
                        app.HealthBar2.ImageSource = imread("hp5.png");
                    case 6
                        app.HealthBar2.ImageSource = imread("hp6.png");
                    case 7
                        app.HealthBar2.ImageSource = imread("hp7.png");
                    case 8
                        app.HealthBar2.ImageSource = imread("hp8.png");
                    case 9
                        app.HealthBar2.ImageSource = imread("hp9.png");
                    otherwise  
                        app.HealthBar2.ImageSource = imread("hp10.png");
                end
            end
            
            % Play Sound File function
            function PLAYSoundFile(filename)
                [y,Fs] = audioread(filename);
                sound(y,Fs);
                clear y Fs;
            end

        
   %  The rest of the code is the same as Player 1's, so that way it doesn't
   %  matter which person rolls first.
   
   %  WARNING: IF YOU ALTER THE BELOW CODE, MAKE SURE THAT PLAYER 1'S CODE 
   %  IS ALSO CHANGED!
            
            
            if num2 > 0
              % Player 1 has the highest roll:
                if num1 > num2
                    
                            conv2num = str2double(app.HealthValue_2.Text);
                            newValue = thingSpeakRead(app.channelID, 'readKey', app.readAPIKey, 'Fields', [4],'OutputFormat','matrix');
                            DISPLAYPlayer2HealthBar(newValue);
                            conv2char = string(newValue);
                            app.HealthValue_2.Text = conv2char;
                            PLAYSoundFile("hiss.mp3");
                            
                          % To prevent health from dropping below zero: 
                            if str2double(app.HealthValue_2.Text) < 0
                                app.HealthValue_2.Text = "0";
                            end
                           
                          % Update Battle Log
                            app.BattleLog.Data = [app.BattleLog.Data;{num1 num2}];
                            
                          % Reset board:
                            pause(3);
                            app.Image2.ImageSource = imread("blank.jpg");
                            %set(app.ROLLButton, 'Enable', 'on');
                            set(app.ROLLButton_2_online, 'Enable', 'on');


              % Player 2 has the highest roll:    
                else
                    
                            conv2num = str2double(app.HealthValue.Text);
                            newValue = thingSpeakRead(app.channelID, 'readKey', app.readAPIKey, 'Fields', [3],'OutputFormat','matrix');
                            DISPLAYPlayer1HealthBar(newValue);
                            conv2char = string(newValue);
                            app.HealthValue.Text = conv2char;
                            PLAYSoundFile("whimper.wav");
                            
                          % To prevent health from dropping below zero:
                            if str2double(app.HealthValue.Text) < 0
                                app.HealthValue.Text = "0";
                            end
                            
                          % Update Battle Log
                            app.BattleLog.Data = [app.BattleLog.Data;{num1 num2}];
                            
                          % Reset board:
                            pause(3);
                            app.Image2.ImageSource = imread("blank.jpg");
                            set(app.ROLLButton_2_online, 'Enable', 'on');
                                
                        
                end
            end
            
          % Player 1 loses:
            if str2double(app.HealthValue.Text) <= 0
                app.HealthValue.Text = "0";
                DISPLAYPlayer1HealthBar(0);
                PLAYSoundFile("victory.mp3");
                score2num = str2double(app.ScoreValue_2.Text);
                newScore = score2num + 1;
                score2string = string(newScore);
                app.ScoreValue_2.Text = score2string;
                app.WinLabel.Text(8) = "2";
                set(app.WinLabel, 'Visible', 'on'); 
              % Reset Game:
                num1 = 0;
                num2 = 0; 
                thingSpeakWrite(app.channelID, 'WriteKey', app.writeAPIKey, 'Fields', [1], 'Values', num1);
                thingSpeakWrite(app.channelID, 'WriteKey', app.writeAPIKey, 'Fields', [2], 'Values', num2);
                %set(app.ROLLButton, 'Enable', 'off');
                set(app.ROLLButton_2_online, 'Enable', 'on');
                pause(3);
                app.Image.ImageSource = imread("blank.jpg");
                app.Image2.ImageSource = imread("blank.jpg");
                DISPLAYPlayer1HealthBar(10);
                DISPLAYPlayer2HealthBar(10);
                app.HealthValue.Text = "10";
                app.HealthValue_2.Text = "10";
                %set(app.ROLLButton, 'Enable', 'on');
                set(app.ROLLButton_2_online, 'Enable', 'on');
                set(app.WinLabel, 'Visible', 'off');
                app.BattleLog.Data = {};
                webwrite(url, weboptions('RequestMethod','delete'));
            end
            
          % Player 2 loses:
            if str2double(app.HealthValue_2.Text) <= 0
                app.HealthValue_2.Text = "0";
                DISPLAYPlayer2HealthBar(0);
                PLAYSoundFile("victory.mp3");
                score2num = str2double(app.ScoreValue.Text);
                newScore = score2num + 1;
                score2string = string(newScore);
                app.ScoreValue.Text = score2string;
                app.WinLabel.Text(8) = "1";
                set(app.WinLabel, 'Visible', 'on');
                
              % Reset Game:
                num1 = 0;
                num2 = 0;
                thingSpeakWrite(app.channelID, 'WriteKey', app.writeAPIKey, 'Fields', [1], 'Values', num1);
                thingSpeakWrite(app.channelID, 'WriteKey', app.writeAPIKey, 'Fields', [2], 'Values', num2);
                %set(app.ROLLButton, 'Enable', 'off');
                set(app.ROLLButton_2_online, 'Enable', 'off');                                       
                pause(3);
                app.Image.ImageSource = imread("blank.jpg");
                app.Image2.ImageSource = imread("blank.jpg");
                DISPLAYPlayer1HealthBar(10);
                DISPLAYPlayer2HealthBar(10);
                app.HealthValue.Text = "10";
                app.HealthValue_2.Text = "10";
                %set(app.ROLLButton, 'Enable', 'on');
                set(app.ROLLButton_2_online, 'Enable', 'on');
                set(app.WinLabel, 'Visible', 'off');
                app.BattleLog.Data = {};
                webwrite(url, weboptions('RequestMethod','delete'));
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {487, 487, 487};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.Player1.Layout.Row = 2;
                app.Player1.Layout.Column = 1;
                app.Player2.Layout.Row = 3;
                app.Player2.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {487, 487};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.Player1.Layout.Row = 2;
                app.Player1.Layout.Column = 1;
                app.Player2.Layout.Row = 2;
                app.Player2.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {163, '1x', 177};
                app.Player1.Layout.Row = 1;
                app.Player1.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.Player2.Layout.Row = 1;
                app.Player2.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Player1 = uipanel(app.GridLayout);
            app.ROLLButton = uibutton(app.Player1, 'push');
            app.ROLLButton.ButtonPushedFcn = createCallbackFcn(app, @ROLLButtonPushed, true);
            app.ROLLButton.Visible = 'off';
            app.ROLLButton.Text = 'ROLL';
            app.SELECTSwitchLabel = uilabel(app.Player1);
            app.SELECTSwitchLabel.Visible = 'off';
            app.SELECTSwitchLabel.Text = 'SELECT:';
            app.SELECTSwitch = uiswitch(app.Player1, 'rocker');
            app.SELECTSwitch.Items = {'HEAL', 'ATTACK'};
            app.SELECTSwitch.Visible = 'off';
            app.SELECTSwitch.Value = 'ATTACK';
            app.ScoreLabel = uilabel(app.Player1);
            app.ScoreLabel.Text = 'Score:';
            app.ScoreValue = uilabel(app.Player1);
            app.ScoreValue.Text = '0';
            app.STARTButton = uibutton(app.Player1, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.ReadyLabel = uilabel(app.Player1);
            app.ReadyLabel.Text = 'Ready?';
            app.Image = uiimage(app.Player1);
            app.Image.Visible = 'off';
            app.ROLLButton_online = uibutton(app.Player1, 'push');
            app.ROLLButton_online.ButtonPushedFcn = createCallbackFcn(app, @ROLLButton_onlinePushed, true);
            app.ROLLButton_online.Visible = 'off';
            app.ROLLButton_online.Text = 'ROLL';
            app.CenterPanel = uipanel(app.GridLayout);
            app.Background = uiimage(app.CenterPanel);
            app.Background.ImageSource = 'back.png';
            app.HealthBar1 = uiimage(app.CenterPanel);
            app.HealthBar1.ImageClickedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.HealthBar1.ImageSource = 'player1hp10.png';
            app.HealthBar2 = uiimage(app.CenterPanel);
            app.HealthBar2.ImageSource = 'hp10.png';
            app.HealthLabel = uilabel(app.CenterPanel);
            app.HealthLabel.Text = 'Health:';
            app.HealthLabel_2 = uilabel(app.CenterPanel);
            app.HealthLabel_2.Text = 'Health:';
            app.HealthValue_2 = uilabel(app.CenterPanel);
            app.HealthValue_2.Visible = 'off';
            app.HealthValue_2.Text = '10';
            app.HealthValue = uilabel(app.CenterPanel);
            app.HealthValue.Visible = 'off';
            app.HealthValue.Text = '10';
            app.Label = uilabel(app.CenterPanel);
            app.Label.Text = '/10';
            app.Label_2 = uilabel(app.CenterPanel);
            app.Label_2.Text = '/10';
            app.Player1Label = uilabel(app.CenterPanel);
            app.Player1Label.Text = 'Player 1';
            app.Player2Label = uilabel(app.CenterPanel);
            app.Player2Label.Text = 'Player 2';
            app.WinLabel = uilabel(app.CenterPanel);
            app.WinLabel.Visible = 'off';
            app.WinLabel.Text = 'PLAYER _ WINS!';
            app.BattleLogButton = uibutton(app.CenterPanel, 'push');
            app.BattleLogButton.ButtonPushedFcn = createCallbackFcn(app, @BattleLogButtonPushed, true);
            app.BattleLogButton.Text = 'Battle Log';
            app.BattleLog = uitable(app.CenterPanel);
            app.BattleLog.ColumnName = {'Player 1 Rolls'; 'Player 2 Rolls'};
            app.BattleLog.RowName = {};
            app.BattleLog.Visible = 'off';
            app.HelpButton = uibutton(app.CenterPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.help = uiimage(app.CenterPanel);
            app.help.Visible = 'off';
            app.help.ImageSource = 'back.png';
            app.OFFLINEButton = uibutton(app.CenterPanel, 'push');
            app.OFFLINEButton.ButtonPushedFcn = createCallbackFcn(app, @OFFLINEButtonPushed, true);
            app.OFFLINEButton.Text = 'OFFLINE';
            app.ONLINEButton = uibutton(app.CenterPanel, 'push');
            app.ONLINEButton.ButtonPushedFcn = createCallbackFcn(app, @ONLINEButtonPushed, true);
            app.ONLINEButton.Text = 'ONLINE';
            app.DogButton = uibutton(app.CenterPanel, 'push');
            app.DogButton.ButtonPushedFcn = createCallbackFcn(app, @DogButtonPushed, true);
            app.DogButton.Visible = 'off';
            app.DogButton.Text = 'Dog';
            app.PickOneLabel = uilabel(app.CenterPanel);
            app.PickOneLabel.Text = 'Pick One';
            app.SnakeButton = uibutton(app.CenterPanel, 'push');
            app.SnakeButton.ButtonPushedFcn = createCallbackFcn(app, @SnakeButtonPushed, true);
            app.SnakeButton.Visible = 'off';
            app.SnakeButton.Text = 'Snake';
            app.Player2 = uipanel(app.GridLayout);
            app.ROLLButton_2 = uibutton(app.Player2, 'push');
            app.ROLLButton_2.ButtonPushedFcn = createCallbackFcn(app, @ROLLButton_2Pushed, true);
            app.ROLLButton_2.Visible = 'off';
            app.ROLLButton_2.Text = 'ROLL';
            app.SELECTSwitch_2 = uiswitch(app.Player2, 'rocker');
            app.SELECTSwitch_2.Items = {'HEAL', 'ATTACK'};
            app.SELECTSwitch_2.Visible = 'off';
            app.SELECTSwitch_2.Value = 'ATTACK';
            app.SELECTSwitch_2Label = uilabel(app.Player2);
            app.SELECTSwitch_2Label.Visible = 'off';
            app.SELECTSwitch_2Label.Text = 'SELECT:';
            app.ScoreLabel_2 = uilabel(app.Player2);
            app.ScoreLabel_2.Text = 'Score:';
            app.ScoreValue_2 = uilabel(app.Player2);
            app.ScoreValue_2.Text = '0';
            app.STARTButton_2 = uibutton(app.Player2, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Text = 'START';
            app.ReadyLabel_2 = uilabel(app.Player2);
            app.ReadyLabel_2.Text = 'Ready?';
            app.Image2 = uiimage(app.Player2);
            app.Image2.Visible = 'off';
            app.ROLLButton_2_online = uibutton(app.Player2, 'push');
            app.ROLLButton_2_online.ButtonPushedFcn = createCallbackFcn(app, @ROLLButton_2_onlinePushed, true);
            app.ROLLButton_2_online.Visible = 'off';
            app.ROLLButton_2_online.Text = 'ROLL';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dicegame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end